classdef Knob_control_light < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        RiskWarningPanel   matlab.ui.container.Panel
        HighRiskLabel      matlab.ui.control.Label
        red                matlab.ui.control.Lamp
        ModerateRiskLabel  matlab.ui.control.Label
        yellow             matlab.ui.control.Lamp
        SafeLabel          matlab.ui.control.Label
        green              matlab.ui.control.Lamp
        KnobLabel          matlab.ui.control.Label
        Knob               matlab.ui.control.DiscreteKnob
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            if isequal(value,'Off')
                app.red.Color=[0 0 0];
                app.yellow.Color=[0 0 0];
                app.green.Color=[0 0 0];
            elseif isequal(value,'Low')
                app.red.Color=[0 0 0];
                app.yellow.Color=[0 0 0];
                app.green.Color=[0 1 0];
            elseif isequal(value,'Medium')
                app.red.Color=[0 0 0];
                app.yellow.Color=[1 1 0];
                app.green.Color=[0 0 0];
            elseif isequal(value,'High')
                app.red.Color=[1 0 0];
                app.yellow.Color=[0 0 0];
                app.green.Color=[0 0 0];
            else
                app.red.Color=[0 0 0];
                app.yellow.Color=[0 0 0];
                app.green.Color=[0 0 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RiskWarningPanel = uipanel(app.UIFigure);
            app.RiskWarningPanel.Title = 'Risk Warning';
            app.HighRiskLabel = uilabel(app.RiskWarningPanel);
            app.HighRiskLabel.Text = 'High Risk';
            app.red = uilamp(app.RiskWarningPanel);
            app.ModerateRiskLabel = uilabel(app.RiskWarningPanel);
            app.ModerateRiskLabel.Text = 'Moderate Risk';
            app.yellow = uilamp(app.RiskWarningPanel);
            app.SafeLabel = uilabel(app.RiskWarningPanel);
            app.SafeLabel.Text = 'Safe';
            app.green = uilamp(app.RiskWarningPanel);
            app.KnobLabel = uilabel(app.RiskWarningPanel);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.RiskWarningPanel, 'discrete');
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Knob_control_light

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end