classdef Light < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Panel              matlab.ui.container.Panel
        HighRiskLabel_2    matlab.ui.control.Label
        red                matlab.ui.control.Lamp
        ModerateRiskLabel  matlab.ui.control.Label
        yellow             matlab.ui.control.Lamp
        SafeLabel          matlab.ui.control.Label
        green              matlab.ui.control.Lamp
        KnobLabel          matlab.ui.control.Label
        Knob               matlab.ui.control.DiscreteKnob
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            
        end

        % Callback function
        function SwitchValueChanged(app, event)

        end

        % Value changed function: Knob
        function KnobValueChanged2(app, event)
            value = app.Knob.Value;
            if isequal(value,'Off')
                app.red.Color=[0 0 0];
                app.yellow.Color=[0 0 0];
                app.green.Color=[0 0 0];
            elseif isequal(value,'Low')
                app.red.Color=[0 0 0];
                app.yellow.Color=[0 0 0];
                app.green.Color=[0 1 0];
            elseif isequal(value,'Medium')
                app.red.Color=[0 0 0];
                app.yellow.Color=[1 1 0];
                app.green.Color=[0 0 0];
            elseif isequal(value,'High')
                app.red.Color=[1 0 0];
                app.yellow.Color=[0 0 0];
                app.green.Color=[0 0 0];
            else
                app.red.Color=[0 0 0];
                app.yellow.Color=[0 0 0];
                app.green.Color=[0 0 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.HighRiskLabel_2 = uilabel(app.Panel);
            app.HighRiskLabel_2.Text = 'High Risk';
            app.red = uilamp(app.Panel);
            app.ModerateRiskLabel = uilabel(app.Panel);
            app.ModerateRiskLabel.Text = 'Moderate Risk';
            app.yellow = uilamp(app.Panel);
            app.SafeLabel = uilabel(app.Panel);
            app.SafeLabel.Text = 'Safe';
            app.green = uilamp(app.Panel);
            app.KnobLabel = uilabel(app.Panel);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.Panel, 'discrete');
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged2, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Light

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end