classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        StepresponseofsecondordersystemsPanel  matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
        TransferfunctionofasecondordersystemLabel  matlab.ui.control.Label
        TFKomega_n2s22zetaomega_nsomega_n2Label  matlab.ui.control.Label
        zetaEditFieldLabel     matlab.ui.control.Label
        zetavalue              matlab.ui.control.NumericEditField
        KEditFieldLabel        matlab.ui.control.Label
        K                      matlab.ui.control.NumericEditField
        Omega_nEditFieldLabel  matlab.ui.control.Label
        Omega_n                matlab.ui.control.NumericEditField
        SystemEditFieldLabel   matlab.ui.control.Label
        System                 matlab.ui.control.EditField
        AmplitudeSlider        matlab.ui.control.Slider
        Plot                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)
            app.zetavalue.Value = app.AmplitudeSlider.Value;
        end

        % Value changed function: zetavalue
        function zetavalueValueChanged(app, event)
            value = app.zetavalue.Value;
            
        end

        % Value changed function: Omega_n
        function Omega_nValueChanged(app, event)
            value = app.Omega_n.Value;
            
        end

        % Value changed function: K
        function KValueChanged(app, event)
            value = app.K.Value;
            
        end

        % Value changed function: System
        function SystemValueChanged(app, event)
            value = app.System.Value;
            
        end

        % Button pushed function: Plot
        function PlotButtonPushed(app, event)
            K=app.K.Value;omega_n=app.Omega_n.Value;
            zeta=app.zetavalue.Value;
            s=tf('s');
            G=(K*omega_n^2)/(s^2+2*zeta*omega_n*s+omega_n^2);
            if zeta>1
                app.System.Value='Overdamped';
            elseif zeta==1
                app.System.Value='Critically damped';
            elseif zeta>0 && zeta<1
                app.System.Value='Underdamped';
            elseif zeta==0
                app.System.Value='Undamped';
            end 
            plot(app.UIAxes, step(G));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StepresponseofsecondordersystemsPanel = uipanel(app.UIFigure);
            app.StepresponseofsecondordersystemsPanel.Title = 'Step response of second order systems';
            app.UIAxes = uiaxes(app.StepresponseofsecondordersystemsPanel);
            title(app.UIAxes, 'Step Response')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TransferfunctionofasecondordersystemLabel = uilabel(app.StepresponseofsecondordersystemsPanel);
            app.TransferfunctionofasecondordersystemLabel.Text = 'Transfer function of a second order system:';
            app.TFKomega_n2s22zetaomega_nsomega_n2Label = uilabel(app.StepresponseofsecondordersystemsPanel);
            app.TFKomega_n2s22zetaomega_nsomega_n2Label.Text = 'TF=[K*omega_n^2]/[s^2 + 2*zeta*omega_n*s + omega_n^2]';
            app.zetaEditFieldLabel = uilabel(app.StepresponseofsecondordersystemsPanel);
            app.zetaEditFieldLabel.Text = 'zeta';
            app.zetavalue = uieditfield(app.StepresponseofsecondordersystemsPanel, 'numeric');
            app.zetavalue.ValueChangedFcn = createCallbackFcn(app, @zetavalueValueChanged, true);
            app.KEditFieldLabel = uilabel(app.StepresponseofsecondordersystemsPanel);
            app.KEditFieldLabel.Text = 'K';
            app.K = uieditfield(app.StepresponseofsecondordersystemsPanel, 'numeric');
            app.K.ValueChangedFcn = createCallbackFcn(app, @KValueChanged, true);
            app.K.Tooltip = {''};
            app.Omega_nEditFieldLabel = uilabel(app.StepresponseofsecondordersystemsPanel);
            app.Omega_nEditFieldLabel.Text = 'Omega_n';
            app.Omega_n = uieditfield(app.StepresponseofsecondordersystemsPanel, 'numeric');
            app.Omega_n.ValueChangedFcn = createCallbackFcn(app, @Omega_nValueChanged, true);
            app.SystemEditFieldLabel = uilabel(app.StepresponseofsecondordersystemsPanel);
            app.SystemEditFieldLabel.Text = 'System';
            app.System = uieditfield(app.StepresponseofsecondordersystemsPanel, 'text');
            app.System.ValueChangedFcn = createCallbackFcn(app, @SystemValueChanged, true);
            app.AmplitudeSlider = uislider(app.StepresponseofsecondordersystemsPanel);
            app.AmplitudeSlider.Limits = [-2 2];
            app.AmplitudeSlider.MajorTicks = [-2 -1 0 1 2];
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.AmplitudeSlider.MinorTicks = [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2];
            app.Plot = uibutton(app.StepresponseofsecondordersystemsPanel, 'push');
            app.Plot.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.Plot.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end